classdef golfApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TuningSlopesDegreesLabel     matlab.ui.control.Label
        SteepEditField               matlab.ui.control.NumericEditField
        SteepEditFieldLabel          matlab.ui.control.Label
        ModerateEditField            matlab.ui.control.NumericEditField
        ModerateEditFieldLabel       matlab.ui.control.Label
        LightEditField               matlab.ui.control.NumericEditField
        LightEditFieldLabel          matlab.ui.control.Label
        Team3AutomatedPuttPuttLabel  matlab.ui.control.Label
        ChooseHoleListBox            matlab.ui.control.ListBox
        ChooseHoleListBoxLabel       matlab.ui.control.Label
        ChooseBallListBox            matlab.ui.control.ListBox
        ChooseBallListBoxLabel       matlab.ui.control.Label
        HitBallButton                matlab.ui.control.Button
        DetectBoardButton            matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        image %Hold image 
        golf %Hold all of golf data 
        light %Store light slope tuning 
        moderate %Store moderate slope tuning 
        steep %Store steep slope tuning 
    end
    
    methods (Access = private)
        
        function plotGolf(app)
            %Grab each centroid, & at each centroid location on current image 
            %Compare the 3 pixels for RGB to determine color, identifying 
            %Golfballs & Golfholes 

            hold(app.UIAxes, 'on');
            for i = 1 : length(app.golf.stats) 
                centroid = app.golf.stats(i).Centroid; 
                plot(app.UIAxes, centroid(1),centroid(2),'kX');
                round(centroid(1));
                round(centroid(2));
                app.image(round(centroid(2)),round(centroid(1)),1);
                app.image(round(centroid(2)),round(centroid(1)),2);
                app.image(round(centroid(2)),round(centroid(1)),3);
            
                %~White: Golfball
                if app.image(round(centroid(2)),round(centroid(1)),1) > 250 && app.image(round(centroid(2)),round(centroid(1)),2) > 250
                    app.golf.numBall = app.golf.numBall + 1;
                    app.golf.locBall(app.golf.numBall, :) = [centroid(1), centroid(2)]; 
                    text(centroid(1)+5,centroid(2)+50,sprintf('BALL#%d', app.golf.numBall), 'Parent', app.UIAxes); 
            
                %Otherwise: Golfhole
                else 
                    app.golf.numHole = app.golf.numHole + 1; 
                    app.golf.locHole(app.golf.numHole, :) = [centroid(1), centroid(2)]; 
                    text(centroid(1)+5,centroid(2)+50,sprintf('HOLE#%d', app.golf.numHole), 'Parent', app.UIAxes); 
                end
            
            end 
            hold(app.UIAxes, 'off'); 
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Add device drivers for the DC motor onto file path upon launch
            addpath(genpath('Device_Drivers')); 
            addpath(genpath('MotorModel_Sp23_V22b_ert_rtw'));
            set_param('MotorModel_Sp23_V22b/aimAngle', 'value', '95');
            pause(2);
            set_param('MotorModel_Sp23_V22b/hitAngle', 'value', '160'); 

            %% Add device drivers for the DC motor onto file path upon launch
            addpath(genpath('Device_Drivers')); 
            addpath(genpath('MotorModel_Sp23_V22b_ert_rtw')); 

            %% Plot startup message onto Figure (Want user to see something more fancy) 
            hold(app.UIAxes, 'on'); 

            %Set background to green, get rid of axi
            set(app.UIAxes,'Color','green', 'XColor', 'none','YColor','none'); 
            
            %Plot text
            txt = 'WELCOME TO \n  PUTT PUTT!';
            text(0.4,0.5,sprintf(txt), 'Parent', app.UIAxes,'FontName', ...
                'Algerian', 'FontSize',50, 'Color', [1 1 1]); 

            hold(app.UIAxes, 'off'); 

            %% Assign thresholds for tuning on startup 
            app.light = app.LightEditField.Value; 
            app.moderate = app.ModerateEditField.Value;
            app.steep = app.SteepEditField.Value; 

        end

        % Button pushed function: DetectBoardButton
        function DetectBoardButtonPushed(app, event)
            %hold(app.UIAxes, 'on');  % Hold for overlaying other elements
            run('sub_cam.m'); 
            app.golf = golf;
            app.image = app.golf.current; 
            imshow(app.image, 'Parent', app.UIAxes); 
            plotGolf(app);
        end

        % Button pushed function: HitBallButton
        function HitBallButtonPushed(app, event)
            
            %% If board has been detected already, allow user to hit
            if ~isempty(app.golf) 
                
                %Take in what user selected 
                numStr = regexp(app.ChooseBallListBox.Value, '\d+', 'match');  % Find the number in the listbox
                selBall = str2double(numStr{1});  % Convert the extracted string to a number 
                numStr = regexp(app.ChooseHoleListBox.Value, '\d+', 'match');  % Find the number in the listbox
                selHole = str2double(numStr{1});  % Convert the extracted string to a number
                valid = 1; %Default to hit being valid, check for invalid later

                %Check if the selected ball is possible
                if (selBall > app.golf.numBall) 
                    valid = 0;
                    msgbox('Invalid Ball! Try detected ball on figure, or Detect Board again')

                %Check if the selected hole is possible
                elseif (selHole > app.golf.numHole) 
                    valid = 0;
                    msgbox('Invalid Hole! Try detected hole on figure, or Detect Board again');
                end 

                %% Valid hole & ball are selected, hit!
                if (valid == 1) 
                    
                    %Define conversions & offsets for use in calculations
                    pixel_in = 350/11.875; %26.1667 pixels per inch (y pixels divided by foot)
                    angle_in = 360/1.55923; %Degrees per inch (1 revolution divided by inches)
                    x_offset = 2.75 * pixel_in; %2.75 inches from board to rail
                    y_offset = 1.8 * pixel_in; %1.8 inches from bottom of board to servo on end of rail

                    %% Determine rail angle 
                    
                    %Determine location of hole & ball
                    ball_x = app.golf.locBall(selBall,1);
                    ball_y = app.golf.locBall(selBall,2); 
                    hole_x = app.golf.locHole(selHole,1);
                    hole_y = app.golf.locHole(selHole,2); 

                    %Find angle for the DC Motor to travel along the rail
                    slope = (hole_y - ball_y)/(hole_x - ball_x); %Fine slope = (y2-y1/x2-x1) 

                    %Determine location of ball (Apply offsets)
                    ball_x = ball_x + x_offset;
                    ball_y = ball_y + y_offset;

                    %Calculate position of rail based off y-intercept
                    rail_location = ball_y - (slope * ball_x); %b = y - mx (Derived from y = mx + b)
                    %rail_location_in = rail_location/pixel_in;
                    rail_angle =  angle_in * (1/pixel_in) * rail_location; %Convert rail from pixel to angle 
                    
                    %Check for valid rail angle (Don't want to fly off rail)
                    if (rail_angle > 3600 || rail_angle < 0) 
                        msgbox('Rail Angle invalid! Calculated %d', rail_angle); 
                        rail_angle = 0; 
                    end


                    %% Adjust Threshold for Aim Angle depending on slope 

                    %Light Slope
                    if (abs(slope) < 0.2)
                        threshold = app.light;
                        %threshold = 0;

                    %Moderate Slope
                    elseif (abs(slope) < 0.35)
                        threshold = app.moderate;
                        %threshold = 0;

                    %Steep Slope
                    else
                        threshold = app.steep;
                        %threshold = 0;
                    end

                    %% Find angle for Servo Motor to aim 
                    angle = atand(abs(slope));
                    if (slope < 0) 
                        aim_angle = 95 + angle + threshold; %tan^-1(x2-x1/y2-y1) or tan^-1(1/slope) 
                    else
                        aim_angle = 95 - angle + threshold; %tan^-1(x2-x1/y2-y1) or tan^-1(1/slope)
                    end
                       
                    %% Set Hit Ball Angle (Lower Angle is going up!)
                    hit_angle = 0;
                    
                    %% Begin Hitting Ball

                    %Send data into rail DC Motor
                    load_system('MotorModel_Sp23_V22b');
                    set_param('MotorModel_Sp23_V22b/desiredPosition', 'value', num2str(rail_angle));
                    pause(5); 
                    
                    %Send data into Aiming Servo Motor
                    set_param('MotorModel_Sp23_V22b/aimAngle', 'value', num2str(aim_angle)); 
                    pause(3); 

                    %Send data into Hitting Servo Motor 
                    set_param('MotorModel_Sp23_V22b/hitAngle', 'value', num2str(hit_angle)); 
                    pause(3); 
                    
                    %Reset Motors
                    set_param('MotorModel_Sp23_V22b/hitAngle', 'value', num2str(160)); %Move hit servo motor to starting position
                    pause(3);

                    set_param('MotorModel_Sp23_V22b/aimAngle', 'value', num2str(95)); %Move aim servo to starting position 
                    pause(3);

                    set_param('MotorModel_Sp23_V22b/desiredPosition', 'value', num2str(0)); %Move DC Motor to starting position
                    
                end

            %% User did not detect board
            else 
                msgbox('Detect the GolfBoard first');
            end 
        end

        % Value changed function: SteepEditField
        function SteepEditFieldValueChanged(app, event)
            app.steep = app.SteepEditField.Value;
            
        end

        % Value changed function: ModerateEditField
        function ModerateEditFieldValueChanged(app, event)
            app.moderate = app.ModerateEditField.Value;
            
        end

        % Value changed function: LightEditField
        function LightEditFieldValueChanged(app, event)
            app.light = app.LightEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Golf Board')
            zlabel(app.UIAxes, 'Z')
            app.DetectBoardButton = uibutton(app.UIFigure, 'push');
            app.DetectBoardButton.ButtonPushedFcn = createCallbackFcn(app, @DetectBoardButtonPushed, true);
            app.DetectBoardButton.Text = 'Detect Board';
            app.HitBallButton = uibutton(app.UIFigure, 'push');
            app.HitBallButton.ButtonPushedFcn = createCallbackFcn(app, @HitBallButtonPushed, true);
            app.HitBallButton.Text = 'Hit Ball';
            app.ChooseBallListBoxLabel = uilabel(app.UIFigure);
            app.ChooseBallListBoxLabel.Text = 'Choose Ball';
            app.ChooseBallListBox = uilistbox(app.UIFigure);
            app.ChooseBallListBox.Items = {'Ball 1 ', 'Ball 2'};
            app.ChooseBallListBox.Value = 'Ball 1 ';
            app.ChooseHoleListBoxLabel = uilabel(app.UIFigure);
            app.ChooseHoleListBoxLabel.Text = 'Choose Hole';
            app.ChooseHoleListBox = uilistbox(app.UIFigure);
            app.ChooseHoleListBox.Items = {'Hole 1 ', 'Hole 2', 'Hole 3'};
            app.ChooseHoleListBox.Value = 'Hole 1 ';
            app.Team3AutomatedPuttPuttLabel = uilabel(app.UIFigure);
            app.Team3AutomatedPuttPuttLabel.Text = 'Team 3: Automated Putt Putt';
            app.LightEditFieldLabel = uilabel(app.UIFigure);
            app.LightEditFieldLabel.Text = 'Light';
            app.LightEditField = uieditfield(app.UIFigure, 'numeric');
            app.LightEditField.ValueChangedFcn = createCallbackFcn(app, @LightEditFieldValueChanged, true);
            app.LightEditField.Value = 2.65;
            app.ModerateEditFieldLabel = uilabel(app.UIFigure);
            app.ModerateEditFieldLabel.Text = 'Moderate';
            app.ModerateEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModerateEditField.ValueChangedFcn = createCallbackFcn(app, @ModerateEditFieldValueChanged, true);
            app.ModerateEditField.Value = 2.65;
            app.SteepEditFieldLabel = uilabel(app.UIFigure);
            app.SteepEditFieldLabel.Text = 'Steep';
            app.SteepEditField = uieditfield(app.UIFigure, 'numeric');
            app.SteepEditField.ValueChangedFcn = createCallbackFcn(app, @SteepEditFieldValueChanged, true);
            app.SteepEditField.Value = 2.65;
            app.TuningSlopesDegreesLabel = uilabel(app.UIFigure);
            app.TuningSlopesDegreesLabel.Text = 'Tuning Slopes (Degrees)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = golfApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end